classdef sunpy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SunpyAppUIFigure  matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        TabGroup          matlab.ui.container.TabGroup
        PlotTab           matlab.ui.container.Tab
        UIAxes            matlab.ui.control.UIAxes
        MapTab            matlab.ui.container.Tab
        Panel             matlab.ui.container.Panel
        SunpyLabel        matlab.ui.control.Label
        UITable           matlab.ui.control.Table
    end

    
    properties (Access = private)
        TT % table
        lat % latitude
        lon % longitude
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setup
            [latitude, longitude, df] = pyrunfile("script.py", [ "latitude" "longitude" "df" ]);
            app.TT = table(df);
            app.TT.Properties.VariableNames = ["time","amount","amount_min"];
            app.UITable.Data = app.TT;
            plot(app.UIAxes,app.TT.time,app.TT.amount)
            app.lat = double(py.numpy.array(latitude)); 
            app.lon = double(py.numpy.array(longitude)); 
            gx = geoaxes(app.Panel);
            geoscatter(gx,app.lat,app.lon, 'filled')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SunpyAppUIFigure = uifigure('Visible', 'off');
            app.SunpyAppUIFigure.Name = 'Sunpy App ☀️';
            app.SunpyAppUIFigure.Icon = fullfile(pathToMLAPP, 'sun_2600-fe0f.png');
            app.GridLayout = uigridlayout(app.SunpyAppUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'time'; 'amount'; 'amount_min'};
            app.UITable.RowName = {};
            app.SunpyLabel = uilabel(app.GridLayout);
            app.SunpyLabel.Text = 'Sunpy ☀️';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amount')
            zlabel(app.UIAxes, 'Z')
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.Panel = uipanel(app.MapTab);
            % Show the figure after all components are created
            app.SunpyAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sunpy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SunpyAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SunpyAppUIFigure)
        end
    end
end